classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MainviewTab                 matlab.ui.container.Tab
        FileselectionPanel          matlab.ui.container.Panel
        LoadfileButton              matlab.ui.control.Button
        SavevideoButton             matlab.ui.control.Button
        batchsizeEditFieldLabel     matlab.ui.control.Label
        batchsizeEditField          matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel   matlab.ui.control.Label
        batchstrideEditField        matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        zEditField                  matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel    matlab.ui.control.Label
        wavelengthEditField         matlab.ui.control.NumericEditField
        FsEditFieldLabel            matlab.ui.control.Label
        FsEditField                 matlab.ui.control.NumericEditField
        f1Label                     matlab.ui.control.Label
        f1EditField                 matlab.ui.control.NumericEditField
        f2EditFieldLabel            matlab.ui.control.Label
        f2EditField                 matlab.ui.control.NumericEditField
        pixwidthEditFieldLabel      matlab.ui.control.Label
        pixwidthEditField           matlab.ui.control.NumericEditField
        pixheightEditFieldLabel     matlab.ui.control.Label
        pixheightEditField          matlab.ui.control.NumericEditField
        registrationCheckBox        matlab.ui.control.CheckBox
        AutofocusButton             matlab.ui.control.Button
        temporalfilterCheckBox      matlab.ui.control.CheckBox
        ParallelismDropDownLabel    matlab.ui.control.Label
        ParallelismDropDown         matlab.ui.control.DropDown
        positioninfileSliderLabel   matlab.ui.control.Label
        positioninfileSlider        matlab.ui.control.Slider
        temporalfilterEditField     matlab.ui.control.NumericEditField
        ImageviewPanel              matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        UIAxes_2                    matlab.ui.control.UIAxes
        GridLayout3                 matlab.ui.container.GridLayout
        UIAxes_1                    matlab.ui.control.UIAxes
        Slider2                     matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        PatientinformationsPanel    matlab.ui.container.Panel
        FirstnameEditFieldLabel     matlab.ui.control.Label
        FirstnameEditField          matlab.ui.control.EditField
        LastnameEditFieldLabel      matlab.ui.control.Label
        LastnameEditField           matlab.ui.control.EditField
        DateofbirthDatePickerLabel  matlab.ui.control.Label
        DateofbirthDatePicker       matlab.ui.control.DatePicker
        NotesTextAreaLabel          matlab.ui.control.Label
        NotesTextArea               matlab.ui.control.TextArea
        Panel                       matlab.ui.container.Panel
    end

    
    properties (Access = private)
        % skip callbacks if no file is loaded
        file_loaded
        
        filename % current file loaded
        filepath
        interferogram_stream
        
        % data related to current displayed frame
        frame_batch
        
        % misc proxy data
        Nx
        Ny
        gw
        kernel
        FH
        hologram
        DX % x circshift for registration
        DY % y circshift for registration
    end
    
    methods (Access = private)
        function compute_kernel(app, use_gpu)
            app.kernel = propagation_kernel(app.Nx, app.Ny, app.zEditField.Value, app.wavelengthEditField.Value, app.pixwidthEditField.Value, app.pixheightEditField.Value, use_gpu);
        end
    
        function compute_FH(app, use_gpu)
            if use_gpu
                app.FH = gpuArray(fftshift(fft2(app.frame_batch)) .* app.kernel);
            else
                app.FH = fftshift(fft2(app.frame_batch)) .* app.kernel;
            end
        end
    
        function compute_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            app.hologram = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu);
        end
    
        function show_hologram(app)
            imshow(mat2gray(app.hologram), 'Parent', app.UIAxes_1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set app constants
            app.gw = 35;
            app.file_loaded = false;
            app.DX = 0;
            app.DY = 0;
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            % open file
            [app.filename, app.filepath] = uigetfile('.cine');
            app.interferogram_stream = CineReader(fullfile(app.filepath, app.filename));
            
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-1024)]; % TODO replace hardcoded 1024
            app.positioninfileSlider.Value = 0;
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            
            % display acquisition information
            app.FsEditField.Value = double(app.interferogram_stream.frame_rate);
            app.f2EditField.Value = floor(app.FsEditField.Value / (2*1000));
            app.pixwidthEditField.Value = 1 / double(app.interferogram_stream.horizontal_pix_per_meter);
            app.pixheightEditField.Value = 1 / double(app.interferogram_stream.vertical_pix_per_meter);
            app.Nx = double(app.interferogram_stream.frame_width);
            app.Ny = double(app.interferogram_stream.frame_height);


            % compute FH
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.file_loaded = true;
            
            
            % display interferogram on 2nd plot
            app.show_hologram();
            imshow(mat2gray(app.frame_batch(:,:,1)), 'Parent', app.UIAxes_2);
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f1EditField
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f2EditField
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: wavelengthEditField
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: batchsizeEditField
        function batchsizeEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: SavevideoButton
        function SavevideoButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
%             [file, path] = uiputfile('.avi');

            % construct filename 
            % name.cine -> name.avi
            % add a number if file already exists
            path = app.filepath;
            [~,file,~] = fileparts(app.filename);
            suffix = 0;
            while exist(fullfile(path, sprintf("%s_%d.avi", file, suffix)))
                suffix = suffix + 1;
            end
            file = sprintf("%s_%d.avi", file, suffix);
            
            %

            tic;
            num_batches = floor((app.interferogram_stream.num_frames - app.batchsizeEditField.Value) / app.batchstrideEditField.Value);
            video = zeros(app.Nx,app.Ny,1,num_batches);
            
            istream = app.interferogram_stream;
            j_win = app.batchsizeEditField.Value;
            j_step = app.batchstrideEditField.Value;
            local_f1 = app.f1EditField.Value;
            local_f2 = app.f2EditField.Value;
            local_gw = app.gw;
            
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            disp(num_batches);
            
            % parfor waitbar
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Saving video...');
            afterEach(D, @update_waitbar);
            N = double(num_batches-1);
            p = 1;

            
            switch app.ParallelismDropDown.Value
                case 'multithread'
                    parfor batch_idx = 1:num_batches-1
                        local_tmp = istream.read_frame_batch(j_win, batch_idx * j_step);
                        local_tmp = fftshift(fft2(local_tmp)) .* local_kernel;
                        local_tmp = reconstruct_hologram(local_tmp, local_f1, local_f2, acquisition, local_gw, false);
                       
                        video(:,:,:,batch_idx) = mat2gray(gather(local_tmp));
                        send(D, 0);  
                    end
                case 'gpu'
                    for batch_idx = 1:num_batches-1
                        app.frame_batch = istream.read_frame_batch(j_win, batch_idx * j_step);
                        app.compute_kernel(false);
                        app.compute_FH(true);
                        app.compute_hologram(true);
                        video(:,:,:,batch_idx) = app.hologram;
                        send(D, 0);
                    end
                case 'singlethread'
                    for batch_idx = 1:num_batches-1
                        app.frame_batch = istream.read_frame_batch(j_win, batch_idx * j_step);
                        app.compute_kernel(false);
                        app.compute_FH(false);
                        app.compute_hologram(false);
                        video(:,:,:,batch_idx) = app.hologram;
                        send(D, 0);
                    end
            end
            
            
            function update_waitbar(~)
                waitbar(p/N, h);
                p = p + 1;
            end
        
            if app.registrationCheckBox.Value
                video = register_video(mat2gray(video));
            end
            
            % guassian temporal filter
            if app.temporalfilterCheckBox.Value
%                 video = reshape(video, size(video,1), size(video,2), size(video,4));
                windowSize = app.temporalfilterEditField.Value; 
                b = (1/windowSize)*ones(1,windowSize);
                a = 1;
                video = filter(b,a,video,[],4);
%                 video = reshape(video, size(video,1), size(video,2), 1, size(video,3));
            end
            
            w = VideoWriter(sprintf('%s\\%s', path, file));
            open(w);
            writeVideo(w, mat2gray(video));
            close(w);
            close(h);
            toc;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            z0 = app.zEditField.Value;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            mask = construct_mask(0,35,app.Nx,app.Ny);
            % free gpu memory
            app.FH = 0;
            z = autofocus(app.frame_batch, app.f1EditField.Value,app.f2EditField.Value,acquisition,z0,mask,app.gw, true);
            app.zEditField.Value = z;
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.FileselectionPanel = uipanel(app.MainviewTab);
            app.FileselectionPanel.Tooltip = {'Laser wavelength in nanometers'};
            app.FileselectionPanel.Title = 'File selection';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.SavevideoButton = uibutton(app.FileselectionPanel, 'push');
            app.SavevideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideoButtonPushed, true);
            app.SavevideoButton.Text = 'Save video';
            app.batchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchsizeEditFieldLabel.Text = 'batch size';
            app.batchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchsizeEditField.Limits = [1 Inf];
            app.batchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @batchsizeEditFieldValueChanged, true);
            app.batchsizeEditField.Value = 512;
            app.batchstrideEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batchstrideEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchstrideEditField.Limits = [1 Inf];
            app.batchstrideEditField.Value = 256;
            app.zEditFieldLabel = uilabel(app.FileselectionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.zEditField.Limits = [0 Inf];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Value = 0.22;
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthEditFieldValueChanged, true);
            app.wavelengthEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.wavelengthEditField.Value = 7.85e-07;
            app.FsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.Editable = 'off';
            app.FsEditField.Tooltip = {'Video sampling frequency in kHz'};
            app.f1Label = uilabel(app.FileselectionPanel);
            app.f1Label.Text = 'f1';
            app.f1EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @f1EditFieldValueChanged, true);
            app.f1EditField.Value = 8;
            app.f2EditFieldLabel = uilabel(app.FileselectionPanel);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @f2EditFieldValueChanged, true);
            app.f2EditField.Value = 30;
            app.pixwidthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.pixwidthEditFieldLabel.Text = 'pix width';
            app.pixwidthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.pixwidthEditField.Limits = [0 Inf];
            app.pixwidthEditField.Editable = 'off';
            app.pixwidthEditField.Tooltip = {'Video sampling frequency in kHz'};
            app.pixheightEditFieldLabel = uilabel(app.FileselectionPanel);
            app.pixheightEditFieldLabel.Text = 'pix height';
            app.pixheightEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.pixheightEditField.Limits = [0 Inf];
            app.pixheightEditField.Editable = 'off';
            app.pixheightEditField.Tooltip = {'Video sampling frequency in kHz'};
            app.registrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.registrationCheckBox.Text = ' registration';
            app.registrationCheckBox.Value = true;
            app.AutofocusButton = uibutton(app.FileselectionPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.temporalfilterCheckBox = uicheckbox(app.FileselectionPanel);
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterCheckBox.Value = true;
            app.ParallelismDropDownLabel = uilabel(app.FileselectionPanel);
            app.ParallelismDropDownLabel.Text = 'Parallelism';
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'multithread', 'gpu', 'singlethread'};
            app.ParallelismDropDown.Value = 'multithread';
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.temporalfilterEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.temporalfilterEditField.Limits = [0 Inf];
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 5;
            app.ImageviewPanel = uipanel(app.MainviewTab);
            app.ImageviewPanel.Title = 'Image view';
            app.GridLayout = uigridlayout(app.ImageviewPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.CLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.CLim = [0 1];
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.Slider2 = uislider(app.GridLayout3);
            app.Slider2.Limits = [-1 1];
            app.Slider2.MajorTicks = [];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.MinorTicks = [];
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [-1 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.PatientinformationsPanel = uipanel(app.GridLayout);
            app.PatientinformationsPanel.Title = 'Patient informations';
            app.FirstnameEditFieldLabel = uilabel(app.PatientinformationsPanel);
            app.FirstnameEditFieldLabel.Text = 'First name';
            app.FirstnameEditField = uieditfield(app.PatientinformationsPanel, 'text');
            app.LastnameEditFieldLabel = uilabel(app.PatientinformationsPanel);
            app.LastnameEditFieldLabel.Text = 'Last name';
            app.LastnameEditField = uieditfield(app.PatientinformationsPanel, 'text');
            app.DateofbirthDatePickerLabel = uilabel(app.PatientinformationsPanel);
            app.DateofbirthDatePickerLabel.Text = 'Date of birth';
            app.DateofbirthDatePicker = uidatepicker(app.PatientinformationsPanel);
            app.DateofbirthDatePicker.Limits = [datetime([1900 1 1]) datetime([2030 12 31])];
            app.DateofbirthDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.NotesTextAreaLabel = uilabel(app.PatientinformationsPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.PatientinformationsPanel);
            app.Panel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end